classdef voc_curation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AddNewVocButton           matlab.ui.control.Button
        ZoomShowButton            matlab.ui.control.Button
        but_UpdateBroadVoc        matlab.ui.control.Button
        txt_BroadPostTime         matlab.ui.control.NumericEditField
        txt_BroadPreTime          matlab.ui.control.NumericEditField
        MakeSafetyCopyButton      matlab.ui.control.Button
        UpdateCuratedVocalizationButton  matlab.ui.control.Button
        DealingwithothercallpropertiesPanel  matlab.ui.container.Panel
        bgroup_IsVoc              matlab.ui.container.ButtonGroup
        but_novoc                 matlab.ui.control.RadioButton
        but_isvoc                 matlab.ui.control.RadioButton
        bgroup_PostFree           matlab.ui.container.ButtonGroup
        but_nonechopost           matlab.ui.control.RadioButton
        but_echopost              matlab.ui.control.RadioButton
        but_postfree              matlab.ui.control.RadioButton
        bgroup_PreFree            matlab.ui.container.ButtonGroup
        but_nonprefree            matlab.ui.control.RadioButton
        but_prefree               matlab.ui.control.RadioButton
        bgroup_EchoNonEcho        matlab.ui.container.ButtonGroup
        but_nonecho               matlab.ui.control.RadioButton
        but_echo                  matlab.ui.control.RadioButton
        AcloselookatthecallPanel  matlab.ui.container.Panel
        lbl_VocDuration           matlab.ui.control.Label
        DurationLabel             matlab.ui.control.Label
        but_UpdateDetailedVoc_2   matlab.ui.control.Button
        lbl_TimeEnd               matlab.ui.control.Label
        VocEndLabel               matlab.ui.control.Label
        lbl_TimeStart             matlab.ui.control.Label
        VocStLabel                matlab.ui.control.Label
        but_UpdateDetailedVoc     matlab.ui.control.Button
        txt_narrowPostTime        matlab.ui.control.NumericEditField
        PostTimeLabel             matlab.ui.control.Label
        txt_narrowPreTime         matlab.ui.control.NumericEditField
        PreTimeEditFieldLabel     matlab.ui.control.Label
        axes_NarrowSpec           matlab.ui.control.UIAxes
        axes_NarrowOsci           matlab.ui.control.UIAxes
        but_PrevVoc               matlab.ui.control.Button
        but_NextVoc               matlab.ui.control.Button
        LoadVocButton             matlab.ui.control.Button
        lbl_MapKey                matlab.ui.control.Label
        MapKeyLabel               matlab.ui.control.Label
        lbl_WavName               matlab.ui.control.Label
        WavNameLabel              matlab.ui.control.Label
        lbl_ColNo                 matlab.ui.control.Label
        ColNoLabel                matlab.ui.control.Label
        txt_VocIdx                matlab.ui.control.NumericEditField
        VocalizationIndexEditFieldLabel  matlab.ui.control.Label
        but_LoadBasicVars         matlab.ui.control.Button
        txt_QuickVarPath          matlab.ui.control.EditField
        VarPathsLabel             matlab.ui.control.Label
        txt_WavsPath              matlab.ui.control.EditField
        WavsPathEditFieldLabel    matlab.ui.control.Label
        axes_BroadSpecVoc         matlab.ui.control.UIAxes
        axes_BroadOsciVoc         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % some metadata
        T = [];
        basic_wavfile_data = [];
        
        % data from the detected vocalizations (merged with pre_time)
        fs_vocs = 250e3;
        voc_detectCurated_map = [];
        
        % some local info from the map, for convenience
        voc2show_idxAll = 0;
        voc2show_mapLocation = [];
        % data info from the call
        voc2show_info = [];
        
        % data useful for loading the wav file
        col_no = 0;
        wavFileName = [];
        
        % data from actual vocalization file
        wav_data = [];
        wav_tvec = [];
        
        % helpful things
        last_curated_idx = -1;
        last_loaded_key = 'null';
        CLim_values =  [-130 -30];
    end
    
    methods (Access = private)
        
        function [] = load_wavfile( app, col_no, filename )
            % build a path for this wavfile
            basepath = app.txt_WavsPath.Value;
            wavfullpath = [ basepath, '\', filename ];        
            [ app.wav_data, app.fs_vocs ] = audioread( wavfullpath ); % load the wav.. that's it (plotting, elsewhere)
            app.wav_tvec = [ 0 : numel( app.wav_data ) - 1 ] ./ app.fs_vocs;
        end
        
        
        function [] = plot_broadwav(app)
            
            % plot the bro oscillogram and spectrograms from the wav
            % in fact, I'm gonna take when the call start, ends and i'm
            % gonna chop from the wav, and then plot. I think this is
            % faster than just plotting and zooming in...
            
            % get me the limits (in samples)
            presamples = ceil( app.txt_BroadPreTime.Value .* app.fs_vocs );
            postsamples = ceil( app.txt_BroadPostTime.Value .* app.fs_vocs );
            plot_st = app.voc2show_info.voc_start + presamples;
            plot_end = min( app.voc2show_info.voc_end + postsamples, numel( app.wav_data ) );
            
            % the plotting now.. oscillogram
            plot( app.axes_BroadOsciVoc, app.wav_tvec( plot_st : plot_end ), app.wav_data( plot_st : plot_end ) ); 
            axis( app.axes_BroadOsciVoc, 'tight' );
            % the spectrogram
            [p, f, t] = pspectrum( app.wav_data( plot_st : plot_end ), app.wav_tvec( plot_st : plot_end ), 'spectrogram', ...
                                   'TimeResolution', 1e-3 );
            axis( app.axes_BroadOsciVoc, 'tight' );
            imagesc( app.axes_BroadSpecVoc, t, f, mag2db( p ) );
            axis( app.axes_BroadSpecVoc, 'tight' );
            app.axes_BroadSpecVoc.YDir = 'normal';
            colormap( app.axes_BroadOsciVoc, 'jet' );
            app.axes_BroadSpecVoc.CLim = app.CLim_values;
        end
        
        
        function [] = plot_narrowwav(app)
            
            % get me the limits (in samples)
            presamples = ceil( app.txt_narrowPreTime.Value ./ 1e3 .* app.fs_vocs );
            postsamples = ceil( app.txt_narrowPostTime.Value ./ 1e3 .* app.fs_vocs );
            plot_st = app.voc2show_info.voc_start + presamples;
            plot_end = min( app.voc2show_info.voc_end + postsamples, numel( app.wav_data ) );
            
            % the plotting now.. oscillogram
            plot( app.axes_NarrowOsci, app.wav_tvec( plot_st : plot_end ), app.wav_data( plot_st : plot_end ) ); 
            axis( app.axes_NarrowOsci, 'tight' );
            % the spectrogram
            [p, f, t] = pspectrum( app.wav_data( plot_st : plot_end ), app.wav_tvec( plot_st : plot_end ), 'spectrogram', ...
                                   'TimeResolution', .4e-3, 'OverlapPercent', 92 );
            axis( app.axes_NarrowOsci, 'tight' );
            imagesc( app.axes_NarrowSpec, t, f, mag2db(p) );
            axis( app.axes_NarrowSpec, 'tight' );
            app.axes_NarrowSpec.YDir = 'normal';
            colormap( app.axes_NarrowSpec, 'jet' );
%             app.axes_NarrowSpec.CLim = app.CLim_values;
            app.axes_NarrowSpec.CLim = [-150 -50];
            colorbar( app.axes_NarrowSpec, 'off' );
        end
        
        
        function [] = show_voc_startend(app)
            
            app.lbl_TimeStart.Text = num2str( app.voc2show_info.voc_start ./ app.fs_vocs );
            app.lbl_TimeEnd.Text = num2str( app.voc2show_info.voc_end ./ app.fs_vocs );
            app.lbl_VocDuration.Text = num2str( (app.voc2show_info.voc_end - app.voc2show_info.voc_start) ./ app.fs_vocs .* 1000 ); % in ms
            
            % show in the axes
            xline( app.axes_BroadOsciVoc, str2double( app.lbl_TimeStart.Text ), 'r' );
            xline( app.axes_BroadOsciVoc, str2double( app.lbl_TimeEnd.Text ), 'r' );
            xline( app.axes_NarrowOsci, str2double( app.lbl_TimeStart.Text ), 'r' );
            xline( app.axes_NarrowOsci, str2double( app.lbl_TimeEnd.Text ), 'r' );
        end
        
        
        function [] = display_vocinfo(app)
            
            % Here I'll update the App controls that have to do with vocalization
            % info. By that I mean is_echo, pre_free, etc...
            
            % is voc_echo
            app.but_echo.Value = app.voc2show_info.is_echo;
            app.but_nonecho.Value = ~app.voc2show_info.is_echo; % just makin sure
            
            % is pre_ree
            app.but_prefree.Value = app.voc2show_info.pre_free;
            app.but_nonprefree.Value = ~app.voc2show_info.pre_free;
            
            % is post_free
            if ( app.voc2show_info.post_free == 0 )
                app.but_postfree.Value = 1;
            elseif ( app.voc2show_info.post_free == 1 )
                app.but_echopost.Value = 1;
            else
                app.but_nonechopost.Value = 1;
            end
            
            % is it a voc?
            app.but_isvoc.Value = app.voc2show_info.is_voc;
            app.but_novoc.Value = ~app.voc2show_info.is_voc;
        end
        
        
        function [] = find_vocidx_andplot(app)
            
            app.CLim_values =  [-120 -30];
            
            cumul_voc_idx = 0;
            mykeys = app.voc_detectCurated_map.keys;
            app.voc2show_idxAll = app.txt_VocIdx.Value;
            
            % find the idx of the call
            for k = 1 : numel( mykeys )
                key = mykeys{ k };
                curr_wav_vocdata = app.voc_detectCurated_map( key );
                if ( cumul_voc_idx + numel( curr_wav_vocdata.is_echo ) >= app.voc2show_idxAll ) break; end;
                cumul_voc_idx = cumul_voc_idx + numel( curr_wav_vocdata.is_echo ); % numel is_echo is a proxy to Ncalls
            end
            
            voc_localidx = app.voc2show_idxAll - cumul_voc_idx; % this variable tells me the idx in the current wav file
            app.voc2show_mapLocation = struct( 'key', key, 'local_idx', voc_localidx );
            
            % get information about this call, start times, end times, etc
            app.voc2show_info = struct( 'voc_start', app.voc_detectCurated_map(key).voc_start(voc_localidx), ...
                                        'voc_end', app.voc_detectCurated_map(key).voc_end(voc_localidx), ...
                                        'is_echo', app.voc_detectCurated_map(key).is_echo(voc_localidx), ...      
                                        'pre_free', app.voc_detectCurated_map(key).pre_free(voc_localidx), ...
                                        'post_free', app.voc_detectCurated_map(key).post_free(voc_localidx) );
            try
                app.voc2show_info.is_voc = app.voc_detectCurated_map(key).is_voc(voc_localidx);
            catch ME
                app.voc2show_info.is_voc = 1;
            end % is_voc is not always necessarily a field...
            
            % get the column number and the file name. Use the key for that
            aux = split( key, '.wav' );
            app.col_no = sscanf( aux{2}, 'Col%d' );
            app.wavFileName = [ aux{1}, '.wav' ];
            
            % set the column number and other visual information...
            app.lbl_ColNo.Text = num2str( app.col_no );
            app.lbl_WavName.Text = app.wavFileName;
            app.lbl_MapKey.Text = key;
            
            % load wav (if necessary) and tell me to plot
            if ( ~strcmp( app.last_loaded_key, key ) )
                load_wavfile( app, app.col_no, app.wavFileName ); % I can use only the key to actually load the wav file...
                app.last_loaded_key = key;
            end
            
            plot_broadwav( app );
            plot_narrowwav( app );
            show_voc_startend( app );
            display_vocinfo( app );
            
            % finally, renew map entry... this guarantees that in the
            % future, even though nothing gets changed from a particular
            % vocalization, all of them have stuff like is_voc, etc...
            update_currvoc_into_map( app );
        end
        
        
        function [] = update_currvoc_into_map( app )
            
            keynow = app.voc2show_mapLocation.key;
            voc_localidx = app.voc2show_mapLocation.local_idx;
            
            entry_map = app.voc_detectCurated_map(keynow);

                entry_map.voc_start(voc_localidx) = app.voc2show_info.voc_start;
                entry_map.voc_end(voc_localidx) = app.voc2show_info.voc_end;
                entry_map.is_echo(voc_localidx) = app.voc2show_info.is_echo;
                entry_map.pre_free(voc_localidx) = app.voc2show_info.pre_free;
                entry_map.post_free(voc_localidx) = app.voc2show_info.post_free;
                entry_map.is_voc(voc_localidx) = app.voc2show_info.is_voc;
                
            app.voc_detectCurated_map(keynow) = entry_map;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: but_LoadBasicVars
        function but_LoadBasicVarsPushed(app, event)
            
            % this load a bunch of basic variables, and stores them into
            % apps properties... (the call is not loaded here)
            
            % e.g. load the arrangement table (might be useful)
            app.T = readtable( [app.txt_QuickVarPath.Value, 'DataArrangement_dummy.xlsx'] );
            % load trigger times
            load( [app.txt_QuickVarPath.Value, 'init_trigger_and_silence.mat'] )
            app.basic_wavfile_data = basic_wavfile_data;

            % get the last curated index, update necessary things
            filesnow = dir( 'curatedVocMaps\*curated_idx*.mat' );
            load( [ filesnow(end).folder, '\', filesnow(end).name ] )
            app.last_curated_idx = last_curated_idx;
            app.txt_VocIdx.Value = last_curated_idx + 1;
            
            % get the latest version of the curated data
            filesnow = dir( 'curatedVocMaps\*detectCurated*.mat' );
            load( [ filesnow(end).folder, '\', filesnow(end).name ] )
            app.voc_detectCurated_map = voc_detectCurated_map;
        end

        % Button pushed function: LoadVocButton
        function LoadVocButtonPushed(app, event)
            
            % load vocalization with index Idx.. I have to look for this
            % vocalization, actualy, since they're listed not
            % straightforwardly for this (in the map, is organized by filename, which is the key)
            find_vocidx_andplot( app );

        end

        % Button pushed function: but_UpdateBroadVoc
        function but_UpdateBroadVocButtonPushed(app, event)
            plot_broadwav( app );
            show_voc_startend( app );
        end

        % Button pushed function: but_UpdateDetailedVoc
        function but_UpdateDetailedVocButtonPushed(app, event)
            plot_narrowwav( app );
            show_voc_startend( app );
        end

        % Button pushed function: but_NextVoc
        function but_NextVocPushed(app, event)
            app.txt_VocIdx.Value = app.txt_VocIdx.Value + 1;
            find_vocidx_andplot(app);
        end

        % Button pushed function: but_PrevVoc
        function but_PrevVocButtonPushed(app, event)
            app.txt_VocIdx.Value = app.txt_VocIdx.Value - 1;
            find_vocidx_andplot(app); 
        end

        % Selection changed function: bgroup_EchoNonEcho
        function bgroup_EchoNonEchoSelectionChanged(app, event)
%             selectedButton = app.bgroup_EchoNonEcho.SelectedObject;
            app.voc2show_info.is_echo = app.but_echo.Value;
            % commit to the ongoing map
            update_currvoc_into_map(app);
        end

        % Selection changed function: bgroup_PreFree
        function bgroup_PreFreeSelectionChanged(app, event)
%             selectedButton = app.bgroup_PreFree.SelectedObject;
            app.voc2show_info.pre_free = app.but_prefree.Value;
            % commit to the ongoing map
            update_currvoc_into_map(app);
        end

        % Selection changed function: bgroup_IsVoc
        function bgroup_IsVocSelectionChanged(app, event)
%             selectedButton = app.bgroup_IsVoc.SelectedObject;
            app.voc2show_info.is_voc = app.but_isvoc.Value;
            % commit to the ongoing map
            update_currvoc_into_map(app);
        end

        % Selection changed function: bgroup_PostFree
        function bgroup_PostFreeSelectionChanged(app, event)
            selectedButton = app.bgroup_PostFree.SelectedObject;
            switch ( string( selectedButton.Text ) )
                case "free"
                    app.voc2show_info.post_free = 0;
                case "echopost"
                    app.voc2show_info.post_free = 1;
                case "nonechopost"
                    app.voc2show_info.post_free = 2;
            end
            % commit to the ongoing map
            update_currvoc_into_map(app);
        end

        % Button pushed function: MakeSafetyCopyButton
        function MakeSafetyCopyButtonPushed(app, event)
            
            app.last_curated_idx = app.txt_VocIdx.Value;
            
            % get me the date now
            dateprefix = datestr( now, 'yymmddHHMM' );
            
            % build the file names
            file2save_map = sprintf( '%s_voc_detectCurated_map.mat', dateprefix );
            file2save_lastidx = sprintf( '%s_voc_last_curated_idx.mat', dateprefix );
            
            % save me the stuff
            voc_detectCurated_map = app.voc_detectCurated_map;
            last_curated_idx = app.last_curated_idx;
            save( [ 'curatedVocMaps\', file2save_map ], 'voc_detectCurated_map' );
            save( [ 'curatedVocMaps\', file2save_lastidx], 'last_curated_idx' );
        end

        % Button pushed function: but_UpdateDetailedVoc_2
        function but_UpdateDetailedVoc_2ButtonPushed(app, event)
            
            % here's the drill... I'll pop another figure. In that figure
            % I'll select (ginput) 2 time points and narrow it down. When
            % the time-points are too close, then I'll stop. Obviously the
            % useful ones will be the ones before the break condition of
            % the loop...
            
            figure(1); clf;
            % I have to plot in this figure whatever I'm looking in my
            % narrow oscillogram....
            
            % get me the limits (in samples)
            presamples = ceil( app.txt_narrowPreTime.Value ./ 1e3 .* app.fs_vocs );
            postsamples = ceil( app.txt_narrowPostTime.Value ./ 1e3 .* app.fs_vocs );
            plot_st = app.voc2show_info.voc_start + presamples;
            plot_end = min( app.voc2show_info.voc_end + postsamples, numel( app.wav_data ) );
            
            % the plotting now.. oscillogram
            plot( app.wav_tvec( plot_st : plot_end ), app.wav_data( plot_st : plot_end ) ); axis tight;
            axis( app.axes_BroadOsciVoc, 'tight' );
            
            % right.... now I have to get myself ginput values
            last_x1 = app.voc2show_info.voc_start ./ app.fs_vocs;
            last_x2 = app.voc2show_info.voc_end ./ app.fs_vocs;
            while (1)
                
                [x, y] = ginput(2); 
                
                if ( abs( diff(x) ) < .01e-3 ) break; end; % do not zoom in if points are too close
                % the above also terminates the ginput
                
                % update the last_xs and zoom in
                last_x1 = min(x); last_x2 = max(x);
                xlim( [min(x) max(x)] );
            end
            
            close( figure(1) ); % close the ginput figure.
            
            % now use the last_xs as the new start and end for the voc  
            app.voc2show_info.voc_start = ceil( last_x1 .* app.fs_vocs );
            app.voc2show_info.voc_end = ceil( last_x2 .* app.fs_vocs );
            % update in the map
            update_currvoc_into_map( app );
            % and replot
            plot_broadwav( app );
            plot_narrowwav( app );
            show_voc_startend( app );
        end

        % Button pushed function: UpdateCuratedVocalizationButton
        function UpdateCuratedVocalizationButtonPushed(app, event)
            update_currvoc_into_map( app );
        end

        % Button pushed function: ZoomShowButton
        function ZoomShowButtonPushed(app, event)
            
            % get me the limits (in samples)
            presamples = ceil( app.txt_BroadPreTime.Value .* app.fs_vocs );
            postsamples = ceil( app.txt_BroadPostTime.Value .* app.fs_vocs );
            plot_st = app.voc2show_info.voc_start + presamples;
            plot_end = min( app.voc2show_info.voc_end + postsamples, numel( app.wav_data ) );
            
            % the plotting now.. oscillogram
            figure(10); clf; subplot( 2, 1, 1 ); 
            plot( app.wav_tvec( plot_st : plot_end ), app.wav_data( plot_st : plot_end ) ); 
            axis tight;
            
            % the spectrogram
            subplot( 2, 1, 2 );
            [p, f, t] = pspectrum( app.wav_data( plot_st : plot_end ), app.wav_tvec( plot_st : plot_end ), 'spectrogram', ...
                                   'TimeResolution', .4e-3, 'OverlapPercent', 92 );
            imagesc( t, f, mag2db( p ) ); axis tight;
            set( gca, 'YDir', 'Normal' ); colormap jet;
            caxis( app.CLim_values );
            
            % zoom in, using the oscillogram
            while (1)
                subplot( 2, 1, 1 );
                [x, y] = ginput(2); 
                if ( abs( diff(x) ) < .1e-3 ) break; end; % do not zoom in if points are too close
                % the above also terminates the ginput
                % update the last_xs and zoom in
                subplot( 2, 1, 1 ); 
                xlim( [min(x) max(x)] );
                subplot( 2, 1, 2 );
                xlim( [min(x) max(x)] );
            end
            
            close( figure(10) );
        end

        % Button pushed function: AddNewVocButton
        function AddNewVocButtonPushed(app, event)
            
            % This should allow me to add a new vocalization to the pool of
            % vocalizations, from a given file. It could be useful...
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_BroadOsciVoc = uiaxes(app.UIFigure);
            xlabel(app.axes_BroadOsciVoc, 'time')
            ylabel(app.axes_BroadOsciVoc, 'amp')
            zlabel(app.axes_BroadOsciVoc, 'Z')
            app.axes_BroadSpecVoc = uiaxes(app.UIFigure);
            xlabel(app.axes_BroadSpecVoc, 'time')
            ylabel(app.axes_BroadSpecVoc, 'amp')
            zlabel(app.axes_BroadSpecVoc, 'Z')
            app.WavsPathEditFieldLabel = uilabel(app.UIFigure);
            app.WavsPathEditFieldLabel.Text = 'WavsPath';
            app.txt_WavsPath = uieditfield(app.UIFigure, 'text');
            app.txt_WavsPath.Value = 'D:\Work\Others\NeuropixelsPreliminary\DataPostProc\prelim_voc_M2axis_Npixels\data\M2-DVAxis-210922-210922_g9_imec0\';
            app.VarPathsLabel = uilabel(app.UIFigure);
            app.VarPathsLabel.Text = 'VarPaths';
            app.txt_QuickVarPath = uieditfield(app.UIFigure, 'text');
            app.txt_QuickVarPath.Value = 'D:\Work\Others\NeuropixelsPreliminary\DataPostProc\prelim_voc_M2axis_Npixels\latest_basicdata\';
            app.but_LoadBasicVars = uibutton(app.UIFigure, 'push');
            app.but_LoadBasicVars.ButtonPushedFcn = createCallbackFcn(app, @but_LoadBasicVarsPushed, true);
            app.but_LoadBasicVars.Text = 'LoadBasicVars';
            app.VocalizationIndexEditFieldLabel = uilabel(app.UIFigure);
            app.VocalizationIndexEditFieldLabel.Text = 'Vocalization Index';
            app.txt_VocIdx = uieditfield(app.UIFigure, 'numeric');
            app.ColNoLabel = uilabel(app.UIFigure);
            app.ColNoLabel.Text = 'ColNo';
            app.lbl_ColNo = uilabel(app.UIFigure);
            app.lbl_ColNo.Text = '-1';
            app.WavNameLabel = uilabel(app.UIFigure);
            app.WavNameLabel.Text = 'WavName';
            app.lbl_WavName = uilabel(app.UIFigure);
            app.lbl_WavName.Text = '-1';
            app.MapKeyLabel = uilabel(app.UIFigure);
            app.MapKeyLabel.Text = 'MapKey';
            app.lbl_MapKey = uilabel(app.UIFigure);
            app.lbl_MapKey.Text = '-1';
            app.LoadVocButton = uibutton(app.UIFigure, 'push');
            app.LoadVocButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVocButtonPushed, true);
            app.LoadVocButton.Text = 'LoadVoc';
            app.but_NextVoc = uibutton(app.UIFigure, 'push');
            app.but_NextVoc.ButtonPushedFcn = createCallbackFcn(app, @but_NextVocPushed, true);
            app.but_NextVoc.Text = '+';
            app.but_PrevVoc = uibutton(app.UIFigure, 'push');
            app.but_PrevVoc.ButtonPushedFcn = createCallbackFcn(app, @but_PrevVocButtonPushed, true);
            app.but_PrevVoc.Text = '-';
            app.AcloselookatthecallPanel = uipanel(app.UIFigure);
            app.AcloselookatthecallPanel.Title = 'A close look at the call';
            app.axes_NarrowOsci = uiaxes(app.AcloselookatthecallPanel);
            zlabel(app.axes_NarrowOsci, 'Z')
            app.axes_NarrowSpec = uiaxes(app.AcloselookatthecallPanel);
            zlabel(app.axes_NarrowSpec, 'Z')
            app.PreTimeEditFieldLabel = uilabel(app.AcloselookatthecallPanel);
            app.PreTimeEditFieldLabel.Text = 'PreTime';
            app.txt_narrowPreTime = uieditfield(app.AcloselookatthecallPanel, 'numeric');
            app.txt_narrowPreTime.Value = -5;
            app.PostTimeLabel = uilabel(app.AcloselookatthecallPanel);
            app.PostTimeLabel.Text = 'PostTime';
            app.txt_narrowPostTime = uieditfield(app.AcloselookatthecallPanel, 'numeric');
            app.txt_narrowPostTime.Value = 5;
            app.but_UpdateDetailedVoc = uibutton(app.AcloselookatthecallPanel, 'push');
            app.but_UpdateDetailedVoc.ButtonPushedFcn = createCallbackFcn(app, @but_UpdateDetailedVocButtonPushed, true);
            app.but_UpdateDetailedVoc.Text = 'Update Voc View';
            app.VocStLabel = uilabel(app.AcloselookatthecallPanel);
            app.VocStLabel.Text = 'VocSt';
            app.lbl_TimeStart = uilabel(app.AcloselookatthecallPanel);
            app.lbl_TimeStart.Text = '-1';
            app.VocEndLabel = uilabel(app.AcloselookatthecallPanel);
            app.VocEndLabel.Text = 'VocEnd';
            app.lbl_TimeEnd = uilabel(app.AcloselookatthecallPanel);
            app.lbl_TimeEnd.Text = '-1';
            app.but_UpdateDetailedVoc_2 = uibutton(app.AcloselookatthecallPanel, 'push');
            app.but_UpdateDetailedVoc_2.ButtonPushedFcn = createCallbackFcn(app, @but_UpdateDetailedVoc_2ButtonPushed, true);
            app.but_UpdateDetailedVoc_2.Text = 'CorrectStartEnd';
            app.DurationLabel = uilabel(app.AcloselookatthecallPanel);
            app.DurationLabel.Text = 'Duration';
            app.lbl_VocDuration = uilabel(app.AcloselookatthecallPanel);
            app.lbl_VocDuration.Text = '-1';
            app.DealingwithothercallpropertiesPanel = uipanel(app.UIFigure);
            app.DealingwithothercallpropertiesPanel.Title = 'Dealing with other call properties';
            app.bgroup_EchoNonEcho = uibuttongroup(app.DealingwithothercallpropertiesPanel);
            app.bgroup_EchoNonEcho.SelectionChangedFcn = createCallbackFcn(app, @bgroup_EchoNonEchoSelectionChanged, true);
            app.bgroup_EchoNonEcho.Title = 'EchoNonEcho';
            app.but_echo = uiradiobutton(app.bgroup_EchoNonEcho);
            app.but_echo.Text = 'echo';
            app.but_echo.Value = true;
            app.but_nonecho = uiradiobutton(app.bgroup_EchoNonEcho);
            app.but_nonecho.Text = 'nonecho';
            app.bgroup_PreFree = uibuttongroup(app.DealingwithothercallpropertiesPanel);
            app.bgroup_PreFree.SelectionChangedFcn = createCallbackFcn(app, @bgroup_PreFreeSelectionChanged, true);
            app.bgroup_PreFree.Title = 'PreFree';
            app.but_prefree = uiradiobutton(app.bgroup_PreFree);
            app.but_prefree.Text = 'free';
            app.but_prefree.Value = true;
            app.but_nonprefree = uiradiobutton(app.bgroup_PreFree);
            app.but_nonprefree.Text = 'nonprefree';
            app.bgroup_PostFree = uibuttongroup(app.DealingwithothercallpropertiesPanel);
            app.bgroup_PostFree.SelectionChangedFcn = createCallbackFcn(app, @bgroup_PostFreeSelectionChanged, true);
            app.bgroup_PostFree.Title = 'PostFree';
            app.but_postfree = uiradiobutton(app.bgroup_PostFree);
            app.but_postfree.Text = 'free';
            app.but_postfree.Value = true;
            app.but_echopost = uiradiobutton(app.bgroup_PostFree);
            app.but_echopost.Text = 'echopost';
            app.but_nonechopost = uiradiobutton(app.bgroup_PostFree);
            app.but_nonechopost.Text = 'nonechopost';
            app.bgroup_IsVoc = uibuttongroup(app.DealingwithothercallpropertiesPanel);
            app.bgroup_IsVoc.SelectionChangedFcn = createCallbackFcn(app, @bgroup_IsVocSelectionChanged, true);
            app.bgroup_IsVoc.Title = 'isVoc';
            app.but_isvoc = uiradiobutton(app.bgroup_IsVoc);
            app.but_isvoc.Text = 'yes';
            app.but_isvoc.Value = true;
            app.but_novoc = uiradiobutton(app.bgroup_IsVoc);
            app.but_novoc.Text = 'no';
            app.UpdateCuratedVocalizationButton = uibutton(app.UIFigure, 'push');
            app.UpdateCuratedVocalizationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCuratedVocalizationButtonPushed, true);
            app.UpdateCuratedVocalizationButton.Text = 'Update Curated Vocalization';
            app.MakeSafetyCopyButton = uibutton(app.UIFigure, 'push');
            app.MakeSafetyCopyButton.ButtonPushedFcn = createCallbackFcn(app, @MakeSafetyCopyButtonPushed, true);
            app.MakeSafetyCopyButton.Text = 'Make Safety Copy';
            app.txt_BroadPreTime = uieditfield(app.UIFigure, 'numeric');
            app.txt_BroadPreTime.Value = -0.5;
            app.txt_BroadPostTime = uieditfield(app.UIFigure, 'numeric');
            app.txt_BroadPostTime.Value = 0.5;
            app.but_UpdateBroadVoc = uibutton(app.UIFigure, 'push');
            app.but_UpdateBroadVoc.ButtonPushedFcn = createCallbackFcn(app, @but_UpdateBroadVocButtonPushed, true);
            app.but_UpdateBroadVoc.Text = 'update';
            app.ZoomShowButton = uibutton(app.UIFigure, 'push');
            app.ZoomShowButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomShowButtonPushed, true);
            app.ZoomShowButton.Text = 'ZoomShow';
            app.AddNewVocButton = uibutton(app.UIFigure, 'push');
            app.AddNewVocButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewVocButtonPushed, true);
            app.AddNewVocButton.Text = 'AddNewVoc';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voc_curation_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end